classdef GUItry < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        StopButton     matlab.ui.control.Button
        RunButton      matlab.ui.control.Button
        ResumeButton   matlab.ui.control.Button
        RestartButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        Running = false; % Logical to track if the script is running
        Paused = false; % Logical to track if the script is paused
        Timer % Timer object to control execution
    end
    
    methods (Access = private)
        
        function startScript(app)
            % % Initialize and run the main robot control script
            % app.Running = true;
            % app.Paused = false;
            % disp('Script started.');
            % 
            % % Call to the main function or initialize environment
            % % initializeEnvironment();  % Function to initialize the environment
            % moveBricks(app);          % Function for main script actions
            % Initialize and run the main robot control script
            app.Running = true;
            app.Paused = false;
            disp('Script started.');

            % Start a timer to control the execution
            app.Timer = timer('ExecutionMode', 'fixedRate', 'Period', 0.1, ...
                'TimerFcn', @(~,~) moveBricks(app));
            start(app.Timer);
        end
        
        function stopScript(app)
             % Stop the script execution
            app.Running = false;
            app.Paused = false;
            disp('Script stopped.');
            % Any cleanup code if needed
        end
        
        function resumeScript(app)
            %  % Resume the script if it was paused
            % if app.Paused
            %     app.Running = true;
            %     app.Paused = false;
            %     disp('Script resumed.');
            %     moveBricks(app); % Continue with main script actions
            % end
            % Stop the script execution
            app.Running = false;
            app.Paused = false;
            disp('Script stopped.');

            % Stop and delete the timer
            if isvalid(app.Timer)
                stop(app.Timer);
                delete(app.Timer);
            end
        end
        
        function restartScript(app)
            % Restart the entire script
            app.stopScript(); % Stop if running
            pause(1); % Small delay to ensure reset
            app.startScript(); % Start fresh
            disp('Script restarted.');
        end
        
        function moveBricks(app)
            %new for the sake of the estop
            if ~app.Running
                return; % Exit if not running
            end

            Main();
            % % This function executes the main robot control script
            % % Place the entire robot control logic here
            % % env = Environment(); % not really needed
            % 
            % %% Set Positions
            % % Adjustment variables
            % sx = -0.5; % variables for shaker position
            % sy = -0.6;
            % sz = 0.9;
            % UR_OS1 = 0.3; % UR10 offset variable
            % Jak_OS1 = 1.0; % Jaka Zu offset
            % 
            % steps_long = 60;
            % steps_short = 30;
            % v_short = 20;
            % 
            % % Environment Variables
            % tablePOS = [-0.4, 0, 0];
            % Bottle1POS = [0, 0.8, 2];
            % Bottle2POS = [-0.5, 0.8, 2];
            % Bottle3POS = [-1, 0.8, 2];
            % Button1POS = [-0.05, 0.8, 1.35];
            % Button2POS = [-0.55, 0.8, 1.35];
            % Button3POS = [-1.05, 0.8, 1.35];
            % LightCurt1POS = [1.7, -1, 1.1];
            % LightCurt2POS = [2.05, -0.4, 0];
            % GlassPOS = [-2, 0, 1.1];
            % ShakerPOS = [sx, sy, sz];
            % EStopPOS = [-2.15, -0.8, 1];
            % FextPOS = [-2, -1.3, 0.5];
            % 
            % % Robot Positions
            % ShakerGrabPOS = [sx, sy, sz];
            % Mid1POS = [-0.30, 0.6, 1.35];
            % Mid2POS = [-0.80, 0.6, 1.35];
            % UR_default = [-1, 0, 1.35];
            % StandPOS = [-0.55, 0, 1.35];
            % HandoffPos = [0.16, 0, 1.96];
            % PourPos = [0.2, -2, 1.2];
            % JakPos0 = [0.16, 0, 2.4];
            % JakPos1 = [0.4, -0.22, 2.43];
            % JakPos2 = [-0.4, -0.22, 2.43];
            % 
            % %% Plot environment
            % [p1, s1, b1, b2, b3, b4, b5, b6, L1, L2, g1, e1, f1] = Environment.simEnvironment(tablePOS, ShakerPOS, Bottle1POS, Bottle2POS, Bottle3POS, Button1POS, Button2POS, Button3POS, LightCurt1POS, LightCurt2POS, GlassPOS, EStopPOS, FextPOS);
            % drawnow();
            % hold on;
            % 
            % %% Plot Jaka Zu 3 Robot 2
            % robot2 = JakaZu3(transl(0, 0, 3) * troty(pi));  % Initialize Jaka Zu 3
            % hold on;
            % qC = robot2.model.getpos();
            % base = robot2.model.fkineUTS(qC);
            % baseTransform = base * trotz(pi/2);
            % shakerHand = ShakerHand(baseTransform);
            % 
            % %% Plot LinearUR10 Robot 1
            % robot1 = LinearUR10(transl(0, 0, 0.5));  % Initialize LinearUR10
            % hold on;
            % 
            % qC = robot1.model.getpos();
            % base = robot1.model.fkineUTS(qC);
            % baseTransform = base * transl(0, 0, 0.1) * trotz(pi/2);
            % 
            % % Apply transformations directly to both fingers
            % finger = RG2Finger(baseTransform);  % Transformation for finger1
            % mfinger = RG2Finger(baseTransform * trotz(pi));  % Transformation for finger2
            % 
            % %% Move to Shaker
            % qTj = Control.CreateTrajectory(robot1, ShakerGrabPOS, steps_long);
            % Control.moveToPos(robot1, qTj, finger, mfinger);
            % 
            % %% Grab Shaker and move to default position
            % qTj = Control.CreateTrajectory(robot1, StandPOS, steps_long);
            % try delete(s1);
            % catch ME
            % end
            % s1NewPos = Control.PlotShaker(robot1, qTj, finger, mfinger);
            % s1 = PlaceObject('ShakerBody.ply', transl(s1NewPos));
            % 
            % %% Move to Drink/Button 1
            % qTj = Control.CreateTrajectory(robot1, Button1POS, steps_long);
            % try delete(s1);
            % catch ME
            % end
            % s1NewPos = Control.PlotShaker(robot1, qTj, finger, mfinger);
            % s1 = PlaceObject('ShakerBody.ply', transl(s1NewPos));
            % 
            % %% Move to midpoint1
            % qTj = Control.CreateTrajectory(robot1, Mid1POS, steps_short);
            % try delete(s1);
            % catch ME
            % end
            % s1NewPos = Control.PlotShaker(robot1, qTj, finger, mfinger);
            % s1 = PlaceObject('ShakerBody.ply', transl(s1NewPos));
            % 
            % %% Move to Button2
            % qTj = Control.CreateTrajectory(robot1, Button2POS, steps_short);
            % try delete(s1);
            % catch ME
            % end
            % s1NewPos = Control.PlotShaker(robot1, qTj, finger, mfinger);
            % s1 = PlaceObject('ShakerBody.ply', transl(s1NewPos));
            % 
            % %% Continue with your movements...
            % 
            % % Complete remaining movements similar to the above pattern...
            % 
            % % Example:
            % % Move to Button 3
            % qTj = Control.CreateTrajectory(robot1, Button3POS, steps_short);
            % try delete(s1);
            % catch ME
            % end
            % s1NewPos = Control.PlotShaker(robot1, qTj, finger, mfinger);
            % s1 = PlaceObject('ShakerBody.ply', transl(s1NewPos));
            % 
            % % Ensure to handle stopping and resuming correctly with the loop
            % % while app.Running && ~app.Paused
            % %     % Your control logic for the robot goes here...
            % %     drawnow();  % Update the GUI
            % % end
            while app.Running && ~app.Paused
                % Execute control logic and movements...
                drawnow;  % Update the GUI and respond to events

                % Include a delay if necessary to manage execution speed
                pause(0.1); % Adjust as needed for performance
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RestartButton
        function RestartCodeButtonPushed(app, event)
            app.restartScript();
        end

        % Button pushed function: ResumeButton
        function ResumeCodeButtonPushed(app, event)
            app.resumeScript();
        end

        % Button pushed function: StopButton
        function StopCodeButtonPushed(app, event)
            app.stopScript();
        end

        % Button pushed function: RunButton
        function RunCodeButtonPushed(app, event)
            app.startScript();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RestartButton = uibutton(app.UIFigure, 'push');
            app.RestartButton.ButtonPushedFcn = createCallbackFcn(app, @RestartCodeButtonPushed, true);
            app.RestartButton.Text = 'Restart';
            app.ResumeButton = uibutton(app.UIFigure, 'push');
            app.ResumeButton.ButtonPushedFcn = createCallbackFcn(app, @ResumeCodeButtonPushed, true);
            app.ResumeButton.Text = 'Resume';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunCodeButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopCodeButtonPushed, true);
            app.StopButton.Text = 'Stop';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUItry

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end